classdef Granulka1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GranulkaUIFigure               matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        NastobrazButton                matlab.ui.control.Button
        PehrtobrazButton               matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        UlozvukButton                  matlab.ui.control.Button
        VolbaoblkygranuleButtonGroup   matlab.ui.container.ButtonGroup
        BezoblkyButton                 matlab.ui.control.RadioButton
        HannovooknoButton              matlab.ui.control.RadioButton
        tverecButton                   matlab.ui.control.RadioButton
        PilaButton                     matlab.ui.control.RadioButton
        SincButton                     matlab.ui.control.RadioButton
        TrvngranulemsSpinnerLabel      matlab.ui.control.Label
        TrvngranulemsSpinner           matlab.ui.control.Spinner
        NovvelikostobrazuSpinnerLabel  matlab.ui.control.Label
        NovvelikostobrazuSpinner       matlab.ui.control.Spinner
        ZobrazspektorgramButton        matlab.ui.control.Button
    end


    properties (Access = private)
        RGB = 0; 
        zvuk = 0;
        text;
        trvanigranule;
        fs = 44100;
        ts = 1/44100; 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                  startRGB = imread('start.jpg');
            image(startRGB, 'parent', app.UIAxes);
            app.TextArea.Value = 'Vítejte v Granulce';
            
        end

        % Button pushed function: NastobrazButton
        function NastobrazButtonPushed(app, event)
             [filename, pathname] = uigetfile( ...
                {'*.jpg;*.jpeg;*.tif;*.png','Obrazove Soubory (*.jpg;*.jpeg;*.tif;*.png)'; ...
                    '*.*',  'Vsechny Soubory (*.*)'});
            drawnow;
            figure(app.GranulkaUIFigure);

        if filename==0
              app.TextArea.Value = 'Nebyl načten žádný obrázek. Zkuste to znovu';
        else
            %% načtení souboru            
            app.RGB = imread( fullfile( pathname, filename));
            image(app.RGB, 'parent', app.UIAxes);
            app.TextArea.Value = join(['Nahrán soubor ',filename]); 
            
        end
        end

        % Button pushed function: UlozvukButton
        function UlozvukButtonPushed(app, event)
             if app.zvuk==0
              app.TextArea.Value = 'Nebyl přehrán žádný zvuk, tudíž není co uložit :(';
            else         
                    [nfilename,npath]=uiputfile('.wav','Uloz zvuk','zvuk.wav');
                    if isequal(nfilename,0) || isequal(npath,0)
                       figure(app.GranulkaUIFigure);
                       return   
                    else
                       nwavfile=fullfile(npath, nfilename);
                       audiowrite(nwavfile,app.zvuk,app.fs);
                       figure(app.GranulkaUIFigure);
                    end
                    figure(app.GranulkaUIFigure);
             end
        end

        % Button pushed function: PehrtobrazButton
        function PehrtobrazButtonPushed(app, event)
                 if app.RGB==0
                      app.TextArea.Value = 'Nebyl načten žádný obraz, tudíž není co přehrát :(';
                      return
                 end
             app.TextArea.Value = 'Vypočítávám...';
             drawnow;
            velikostobrazu =  app.NovvelikostobrazuSpinner.Value;
            aRGB = app.RGB;
                hsv = rgb2hsv(aRGB);
                aRGB = im2double(aRGB);
                %% downsample
                [x,y,z]=size(aRGB);
                newsize = app.NovvelikostobrazuSpinner.Value;                 %číso určuje, na jakou hodnotu se má zaokrouhlit
                
                            
                %% new downsample
                while x~=newsize
                    
                    x = round(x,-1);
                    xx=lcm(x,newsize);
                    mult = xx/x;
                    divide = xx/newsize;
                   R = resample(aRGB(:,:,1),mult,divide);     
                   G = resample(aRGB(:,:,2),mult,divide);    
                   B = resample(aRGB(:,:,3),mult,divide);
                   
                   mR = mod(size(R,1),newsize);
                   R = R(1:end-mR,:);
                   mG = mod(size(G,1),newsize);
                   G = G(1:end-mG,:);
                   mB = mod(size(B,1),newsize);
                   B = B(1:end-mB,:);
                   
                   aRGB = cat(3,R,G,B);
                   aRGB = permute(aRGB,[2 1 3]);
                   [x,y,z]=size(aRGB); 
                   
                end
                
                %% převody
                %RGB
                
                R = aRGB(:,:,1);
                G = aRGB(:,:,2);
                B = aRGB(:,:,3);
           
                %HSV
                hsv = rgb2hsv(aRGB);
                H =hsv(:,:,1); %0 - 1 hue odstín - pouze barva; H=0 odstíny šedi 
                S =hsv(:,:,2); %sytost - pod 20% je to stupeň šedi, 
                V =hsv(:,:,3); %hodnota jasu - množství bílého světla - 0.9<= bílá, 5>černá,
                
                %YCbCr
                YCbCr = rgb2ycbcr(aRGB);
                Y = YCbCr(:,:,1);
                                
                %% přepočet veličin na použitelné čísla
                Red = 1500+R*10000;
                Green =1000+G*2000;
                Blue = 50+B*200;
                Saturation = 400+S*500;
                Hue = 100+H*15000;
                Value = V;
                
                %% definice proměnných pro tvorbu zvuku
                               
                app.trvanigranule = app.TrvngranulemsSpinner.Value;                    %v ms
                velikostokna=(app.fs/1000)*app.trvanigranule;
                n = 0:velikostokna-1;                   %počet vzorků pro jednu granuly 
                t = n/app.fs;
               
                %% používaná obálka 
            if app.BezoblkyButton.Value==true
                ASR = ones(1,length(t));
            elseif app.HannovooknoButton.Value==true
                Hann = hann(velikostokna,'periodic'); %,'periodic'
                ASR = Hann';
            elseif app.tverecButton.Value==true
                ASR = square(t*0.8/(4*pi),50);
            elseif app.PilaButton.Value==true
                ASR = sawtooth(t*0.8/(2*pi));
            else
                ASR = diric(t/(2*pi),8);
            end     
    
                %% příprava matic pro tvorbu zvuku
                Y = spiralTransform(Y,0);                
                Value = spiralTransform(Value,0);                
                Red = spiralTransform(Red,0);                
                Green = spiralTransform(Green,0);                
                Blue = spiralTransform(Blue,0);                
                Saturation = spiralTransform(Saturation,0);                
                Hue = spiralTransform(Hue,0);
                                
                %% plnění matice pro zvuk
                sx=0;
                i=0;
                
                for i = 1: length(Y)        
                        s = Y(i)*sin(2*pi*Red(i)*t)...
                           +Y(i)*sin(2*pi*Green(i)*t)...
                           +Y(i)*sin(2*pi*Blue(i)*t)...
                           +Y(i)*sin(2*pi*Saturation(i)*t)...
                           +Y(i)*sin(2*pi*Hue(i)*t);
                       s = s.*ASR;
                   sx = horzcat(sx,s);   
                end
                sx = sx(1, 2:end);
                sx = sx';
                sx = sx/max(abs(sx));
                
                %% filtrování podle lidského sluchu
                f = logspace(log10(20),log10(20000),length(sx));
                TiQ_dB = 3.64*(f/1000).^(-0.8) - 6.5*exp(-0.6*(f/1000-3.3).^2) + 10.^(-3)*(f/1000).^4;
                TiQ = 10.^(TiQ_dB/20);
                TiQ_norm = TiQ./max(TiQ);
                SX = fft(sx);
                TIQ = fft(TiQ_norm');
                newsx = ifft(SX+TIQ);
                
                %% zvuk a zobrazení
                app.zvuk = newsx/max(abs(newsx))/sqrt(2);
                app.TextArea.Value = 'přehrávám...';
                sound(app.zvuk,app.fs);
               
                pause(velikostobrazu.^2*0.001*app.TrvngranulemsSpinner.Value);
                app.TextArea.Value = 'Granule !!';
                
        end

        % Value changed function: NovvelikostobrazuSpinner
        function NovvelikostobrazuSpinnerValueChanged(app, event)
         
            if app.RGB==0
                app.TextArea.Value = ('Nejprve načtěte obraz');
                app.NovvelikostobrazuSpinner.Value = 30;
            return
            end
            
             if app.NovvelikostobrazuSpinner.Value>50
                app.TextArea.Value = ('Pozor na vysoký počet prvků!');
             end
            [x,y,z] = size(app.RGB);
           
            if x<app.NovvelikostobrazuSpinner.Value
                ph = num2str(x);
                app.NovvelikostobrazuSpinner.Value = x;
                app.TextArea.Value =strcat('Nemůžete zvolit více harmonických, než je velikost obrazu. Max. hodnota je ',ph);
                return
            end
          
        end

        % Callback function
        function VolbaoblkygranuleButtonGroupSelectionChanged(app, event)
     
        end

        % Button pushed function: ZobrazspektorgramButton
        function ZobrazspektorgramButtonPushed(app, event)
            if app.zvuk==0
              app.TextArea.Value = 'Nebyl přehrán žádný zvuk, tudíž není co zobrazit :(';
            else         
                figure('Name','Spektrogram','NumberTitle','off');
                N = 2048;
                [yy,f,t,p] = spectrogram(app.zvuk,hanning(N),N/2,N,app.fs,'yaxis');
                surf(t,f,10*log10(abs(p*app.fs)),'EdgeColor','none','marker','square'); 
                xlabel('{\itt} (s) \rightarrow');
                ylabel('{\itf} (Hz) \rightarrow');
                ylim ([20 20000]);
                caxis([-50 0]);
                axis tight; colormap(jet); view(0,90);
                colorbar
                set(gca,'layer','top','box','on','yscale','log','ylim',[20 20000])
             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GranulkaUIFigure = uifigure;
            app.GranulkaUIFigure.Name = 'Granulka';
            app.UIAxes = uiaxes(app.GranulkaUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.NastobrazButton = uibutton(app.GranulkaUIFigure, 'push');
            app.NastobrazButton.ButtonPushedFcn = createCallbackFcn(app, @NastobrazButtonPushed, true);
            app.NastobrazButton.Text = 'Načíst obraz';
            app.PehrtobrazButton = uibutton(app.GranulkaUIFigure, 'push');
            app.PehrtobrazButton.ButtonPushedFcn = createCallbackFcn(app, @PehrtobrazButtonPushed, true);
            app.PehrtobrazButton.Text = 'Přehrát obraz';
            app.TextArea = uitextarea(app.GranulkaUIFigure);
            app.TextArea.Editable = 'off';
            app.UlozvukButton = uibutton(app.GranulkaUIFigure, 'push');
            app.UlozvukButton.ButtonPushedFcn = createCallbackFcn(app, @UlozvukButtonPushed, true);
            app.UlozvukButton.Text = 'Ulož zvuk';
            app.VolbaoblkygranuleButtonGroup = uibuttongroup(app.GranulkaUIFigure);
            app.VolbaoblkygranuleButtonGroup.Title = 'Volba obálky granule';
            app.BezoblkyButton = uiradiobutton(app.VolbaoblkygranuleButtonGroup);
            app.BezoblkyButton.Text = 'Bez obálky';
            app.BezoblkyButton.Value = true;
            app.HannovooknoButton = uiradiobutton(app.VolbaoblkygranuleButtonGroup);
            app.HannovooknoButton.Text = 'Hannovo okno';
            app.tverecButton = uiradiobutton(app.VolbaoblkygranuleButtonGroup);
            app.tverecButton.Text = 'Čtverec';
            app.PilaButton = uiradiobutton(app.VolbaoblkygranuleButtonGroup);
            app.PilaButton.Text = 'Pila';
            app.SincButton = uiradiobutton(app.VolbaoblkygranuleButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.TrvngranulemsSpinnerLabel = uilabel(app.GranulkaUIFigure);
            app.TrvngranulemsSpinnerLabel.Text = 'Trvání granule [ms]';
            app.TrvngranulemsSpinner = uispinner(app.GranulkaUIFigure);
            app.TrvngranulemsSpinner.Step = 5;
            app.TrvngranulemsSpinner.Limits = [10 100];
            app.TrvngranulemsSpinner.ValueDisplayFormat = '%.1f';
            app.TrvngranulemsSpinner.Value = 20;
            app.NovvelikostobrazuSpinnerLabel = uilabel(app.GranulkaUIFigure);
            app.NovvelikostobrazuSpinnerLabel.Text = 'Nová velikost obrazu';
            app.NovvelikostobrazuSpinner = uispinner(app.GranulkaUIFigure);
            app.NovvelikostobrazuSpinner.Limits = [10 100];
            app.NovvelikostobrazuSpinner.ValueDisplayFormat = '%.1f';
            app.NovvelikostobrazuSpinner.ValueChangedFcn = createCallbackFcn(app, @NovvelikostobrazuSpinnerValueChanged, true);
            app.NovvelikostobrazuSpinner.Value = 30;
            app.ZobrazspektorgramButton = uibutton(app.GranulkaUIFigure, 'push');
            app.ZobrazspektorgramButton.ButtonPushedFcn = createCallbackFcn(app, @ZobrazspektorgramButtonPushed, true);
            app.ZobrazspektorgramButton.Text = 'Zobraz spektorgram';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Granulka1_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GranulkaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GranulkaUIFigure)
        end
    end
end