classdef bzucak1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BzucakUIFigure                  matlab.ui.Figure
        NastobrazButton                 matlab.ui.control.Button
        ZvolpoetharmonickchSliderLabel  matlab.ui.control.Label
        ZvolpoetharmonickchSlider       matlab.ui.control.Slider
        PehrtobrazButton                matlab.ui.control.Button
        UlozvukButton                   matlab.ui.control.Button
        DobapehrvnsSpinnerLabel         matlab.ui.control.Label
        DobapehrvnsSpinner              matlab.ui.control.Spinner
        HiCutFilterKnobLabel            matlab.ui.control.Label
        HiCutFilterKnob                 matlab.ui.control.Knob
        UIAxes                          matlab.ui.control.UIAxes
        PomrvelikostiharmonickchButtonGroup  matlab.ui.container.ButtonGroup
        PodleobrazuButton               matlab.ui.control.RadioButton
        LinernklesajcButton             matlab.ui.control.RadioButton
        LogaritmickybeznulyButton       matlab.ui.control.RadioButton
        LogaritminckysnulouButton       matlab.ui.control.RadioButton
        NhodnButton                     matlab.ui.control.RadioButton
        PotenfzeButtonGroup             matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        NhodnButton_2                   matlab.ui.control.RadioButton
        TextArea                        matlab.ui.control.TextArea
    end


    properties (Access = private)
      %  startRGB            % první obraz
        RGB=0               % Nahraný obraz
        zvuk=0              % výsledný zvuk
        counter
        fm                  % mezní frekvence hi cut filtru
        fs=44100            %vzorkovací frekvence
        text                %zobrazovaný text v callbacku
        prumer_rozdelenych_double
        prumer_celkem
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startRGB = imread('start.jpg');
            image(startRGB, 'parent', app.UIAxes);
            app.TextArea.Value = 'Vítejte v bzučící aplikaci';
            
            % výpočet počátečních hodnot
            app.fm=200+18*2^app.HiCutFilterKnob.Value;
            app.RGB = 0;
        end

        % Button pushed function: NastobrazButton
        function NastobrazButtonPushed(app, event)
            
            [filename, pathname] = uigetfile( ...
                {'*.jpg;*.jpeg;*.tif;*.png','Obrazove Soubory (*.jpg;*.jpeg;*.tif;*.png)'; ...
                    '*.*',  'Vsechny Soubory (*.*)'});
            drawnow;
            figure(app.BzucakUIFigure);
%             app.BzucakUIFigure.Visible = 'off';
% app.BzucakUIFigure.Visible = 'on';
        if filename==0
              app.TextArea.Value = 'Nebyl načten žádný obrázek. Zkuste to znovu';
        else
            %% načtení souboru            
            app.RGB = imread( fullfile( pathname, filename));
            image(app.RGB, 'parent', app.UIAxes);
            app.TextArea.Value = join(['Nahrán soubor ',filename]);  
            
            %definice proměnných
            ph = round(app.ZvolpoetharmonickchSlider.Value);
            
            %% double
           
% 
%             n = numel(prumer_radku);
%             blok = floor(n/ph) ;
%             for i=1:ph
%                  rozdeleny(i,:) = prumer_radku(1,1+blok*(i-1):blok*(i));   
%             end
%             %rozdeleny = rozdeleny';
%             prumer_rozdelenych=mean(rozdeleny');
        end
        end

        % Button pushed function: PehrtobrazButton
        function PehrtobrazButtonPushed(app, event)
            %proměnné
            ph = round(app.ZvolpoetharmonickchSlider.Value);
            mezni_frekvence =app.fm;
            if app.RGB==0
              app.TextArea.Value = 'Nebyl načten žádný obraz, tudíž není co přehrát :(';
            else
 %% hramonicke
            harm_rada = 1:100;
            harm_c = harm_rada(1:ph);
            
            %% double
             Gray_double = im2double(rgb2gray(app.RGB));
%           prumer_sloupcu_double = mean(Gray_double);
            prumer_radku_double =mean(Gray_double');
%           prumer_celkem_double=mean(prumer_sloupcu_double);
            %Gray_double = Gray_double';
            n_double = numel(prumer_radku_double);
            blok_double = floor(n_double/ph) ;
            for i=1:ph
                rozdeleny_double(i,:) = prumer_radku_double(1,1+blok_double*(i-1):blok_double*(i));   
            end
            %rozdeleny = rozdeleny';
            app.prumer_rozdelenych_double=mean(rozdeleny_double');
           
            
            % uint8
            Gray = rgb2gray(app.RGB);
            prumer_sloupcu = mean(Gray);
%           prumer_radku =mean(Gray');
            app.prumer_celkem=mean(prumer_sloupcu);
            %Gray = Gray';
            
if app.PodleobrazuButton.Value==true
    Aharm1 = ones(1,ph);
    
    Aharm2 = ones(1,ph); 
elseif app.LinernklesajcButton.Value==true
    Aharm1 = linspace(1,0,ph);
    
    Aharm2 = linspace(1,0,ph);
elseif app.LogaritmickybeznulyButton.Value==true
    Aharm1 = logspace(1,0,ph);
    Aharm1 = Aharm1/10;
    
    Aharm2 = logspace(1,0,ph);
    Aharm2 = Aharm2/10;
elseif app.LogaritminckysnulouButton.Value==true
    Aharm1 = linspace(1,0,ph);
    Aharm1 = Aharm1.^(3);
    
    Aharm2 = linspace(1,0,ph);
    Aharm2 = Aharm2.^(3);
else
    Aharm1 = randi([0 100],[1 ph]);
    Aharm1 = Aharm1/100;
    
    Aharm2 = randi([0 100],[1 ph]);
    Aharm2 = Aharm2/100;
end      

 %% fáze
 if app.Button.Value==true
     phi1 = zeros(1,ph);
     phi2 = zeros(1,ph);
 else
     phi1 = randi([0 180],[1 ph]);
     phi2 = randi([0 180],[1 ph]);
 end
 
%% generátor časové osy

n = 0:app.fs*app.DobapehrvnsSpinner.Value-1;
t = n/app.fs;%sec 
sl = 0;
sr = 0;
sxl = 0; 
sxr = 0;

    for j=1:ph
    sl = Aharm1(j).*app.prumer_rozdelenych_double(j)*cos(2*pi*app.prumer_celkem*harm_c(j)*t+phi1(j));
    sr = Aharm2(j).*app.prumer_rozdelenych_double(j)*cos(2*pi*app.prumer_celkem*harm_c(j)*t+phi2(j));
    sxl = sxl + sl;
    sxr = sxr + sr;    
    end
    
    sxl = sxl/max(abs(sxl))/sqrt(2);
    sxr = sxr/max(abs(sxr))/sqrt(2);
    
    sxl = lowpass(sxl,mezni_frekvence,app.fs);
    sxr = lowpass(sxr,mezni_frekvence,app.fs);
    
    app.zvuk =[sxl' sxr'];
   % app.zvuk = lowpass(app.zvuk,mezni_frekvence,app.fs);
     
    app.TextArea.Value = 'přehrávám...';
    sound(app.zvuk,app.fs);
    pause(app.DobapehrvnsSpinner.Value+0.2);
    app.TextArea.Value = 'Zabzučeno!';
            end
        end

        % Value changed function: ZvolpoetharmonickchSlider
        function ZvolpoetharmonickchSliderValueChanged(app, event)
            if app.RGB==0
                app.TextArea.Value = ('Nejprve načtěte obraz');
                app.ZvolpoetharmonickchSlider.Value = 10;
            else
            [x,y,z] = size(app.RGB);
            ph = num2str(round(app.ZvolpoetharmonickchSlider.Value));
            if x>=app.ZvolpoetharmonickchSlider.Value
            app.TextArea.Value =strcat('Počet harmonických = ',ph); 
            else
                ph = num2str(x);
                app.ZvolpoetharmonickchSlider.Value = x;
                app.TextArea.Value =strcat('Nemůžete zvolit více harmonických, než je velikost obrazu. Max. hodnota je ',ph);
                return
            end
            end
        end

        % Button pushed function: UlozvukButton
        function UlozvukButtonPushed(app, event)
            if app.zvuk==0
              app.TextArea.Value = 'Nebyl přehrán žádný zvuk, tudíž není co uložit :(';
            else         
                    [nfilename,npath]=uiputfile('.wav','Uloz zvuk','zvuk.wav');
                    if isequal(nfilename,0) || isequal(npath,0)
                       return   
                    else
                       nwavfile=fullfile(npath, nfilename);
                       audiowrite(nwavfile,app.zvuk,app.fs);
                       figure(app.BzucakUIFigure);
                    end
             end
        end

        % Callback function
        function DobapehrvnsSpinnerValueChanged(app, event)
          
        end

        % Callback function: HiCutFilterKnob
        function ZmenaHiCutFreq(app, event)
            app.fm=200+18*2^app.HiCutFilterKnob.Value;
            knobstr = num2str(app.HiCutFilterKnob.Value);
            app.TextArea.Value = strcat('Hodnota Hi-Cut knobu = ',knobstr);
        end

        % Callback function
        function TextAreaValueChanged(app, event)
           
        end

        % Callback function
        function DobapehrvnsSpinnerValueChanged2(app, event)
          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BzucakUIFigure = uifigure;
            app.BzucakUIFigure.Name = 'Bzucak';
            app.NastobrazButton = uibutton(app.BzucakUIFigure, 'push');
            app.NastobrazButton.ButtonPushedFcn = createCallbackFcn(app, @NastobrazButtonPushed, true);
            app.NastobrazButton.Text = 'Načíst obraz';
            app.ZvolpoetharmonickchSliderLabel = uilabel(app.BzucakUIFigure);
            app.ZvolpoetharmonickchSliderLabel.Text = 'Zvol počet harmonických';
            app.ZvolpoetharmonickchSlider = uislider(app.BzucakUIFigure);
            app.ZvolpoetharmonickchSlider.Limits = [1 100];
            app.ZvolpoetharmonickchSlider.MajorTicks = [1 21 41 61 81 100];
            app.ZvolpoetharmonickchSlider.ValueChangedFcn = createCallbackFcn(app, @ZvolpoetharmonickchSliderValueChanged, true);
            app.ZvolpoetharmonickchSlider.Value = 10;
            app.PehrtobrazButton = uibutton(app.BzucakUIFigure, 'push');
            app.PehrtobrazButton.ButtonPushedFcn = createCallbackFcn(app, @PehrtobrazButtonPushed, true);
            app.PehrtobrazButton.Text = 'Přehrát obraz';
            app.UlozvukButton = uibutton(app.BzucakUIFigure, 'push');
            app.UlozvukButton.ButtonPushedFcn = createCallbackFcn(app, @UlozvukButtonPushed, true);
            app.UlozvukButton.Text = 'Ulož zvuk';
            app.DobapehrvnsSpinnerLabel = uilabel(app.BzucakUIFigure);
            app.DobapehrvnsSpinnerLabel.Text = 'Doba přehrávání [s]';
            app.DobapehrvnsSpinner = uispinner(app.BzucakUIFigure);
            app.DobapehrvnsSpinner.Step = 0.1;
            app.DobapehrvnsSpinner.Limits = [0.1 10];
            app.DobapehrvnsSpinner.ValueDisplayFormat = '%.1f';
            app.DobapehrvnsSpinner.Value = 1;
            app.HiCutFilterKnobLabel = uilabel(app.BzucakUIFigure);
            app.HiCutFilterKnobLabel.Text = 'Hi Cut Filter';
            app.HiCutFilterKnob = uiknob(app.BzucakUIFigure, 'continuous');
            app.HiCutFilterKnob.Limits = [1 10];
            app.HiCutFilterKnob.ValueChangedFcn = createCallbackFcn(app, @ZmenaHiCutFreq, true);
            app.HiCutFilterKnob.ValueChangingFcn = createCallbackFcn(app, @ZmenaHiCutFreq, true);
            app.HiCutFilterKnob.MinorTicks = [];
            app.HiCutFilterKnob.Value = 10;
            app.UIAxes = uiaxes(app.BzucakUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.PomrvelikostiharmonickchButtonGroup = uibuttongroup(app.BzucakUIFigure);
            app.PomrvelikostiharmonickchButtonGroup.Title = 'Poměr velikosti harmonických';
            app.PodleobrazuButton = uiradiobutton(app.PomrvelikostiharmonickchButtonGroup);
            app.PodleobrazuButton.Text = 'Podle obrazu';
            app.PodleobrazuButton.Value = true;
            app.LinernklesajcButton = uiradiobutton(app.PomrvelikostiharmonickchButtonGroup);
            app.LinernklesajcButton.Text = 'Lineárně klesající';
            app.LogaritmickybeznulyButton = uiradiobutton(app.PomrvelikostiharmonickchButtonGroup);
            app.LogaritmickybeznulyButton.Text = 'Logaritmicky bez nuly';
            app.LogaritminckysnulouButton = uiradiobutton(app.PomrvelikostiharmonickchButtonGroup);
            app.LogaritminckysnulouButton.Text = 'Logaritmincky s nulou';
            app.NhodnButton = uiradiobutton(app.PomrvelikostiharmonickchButtonGroup);
            app.NhodnButton.Text = 'Náhodně';
            app.PotenfzeButtonGroup = uibuttongroup(app.BzucakUIFigure);
            app.PotenfzeButtonGroup.Title = 'Počáteční fáze';
            app.Button = uiradiobutton(app.PotenfzeButtonGroup);
            app.Button.Text = '0°';
            app.Button.Value = true;
            app.NhodnButton_2 = uiradiobutton(app.PotenfzeButtonGroup);
            app.NhodnButton_2.Text = 'Náhodně';
            app.TextArea = uitextarea(app.BzucakUIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bzucak1_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BzucakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BzucakUIFigure)
        end
    end
end